classdef aplicacion_modelos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        SusceptiblesEditFieldLabel      matlab.ui.control.Label
        SusceptiblesEditField           matlab.ui.control.NumericEditField
        InfectadosEditFieldLabel        matlab.ui.control.Label
        InfectadosEditField             matlab.ui.control.NumericEditField
        RecuperadosEditFieldLabel       matlab.ui.control.Label
        RecuperadosEditField            matlab.ui.control.NumericEditField
        ExpuestosEditFieldLabel         matlab.ui.control.Label
        ExpuestosEditField              matlab.ui.control.NumericEditField
        TasadetransmisinEditFieldLabel  matlab.ui.control.Label
        TasadetransmisinEditField       matlab.ui.control.NumericEditField
        TasaderecuperacinEditFieldLabel  matlab.ui.control.Label
        TasaderecuperacinEditField      matlab.ui.control.NumericEditField
        TasadeincubacinEditFieldLabel   matlab.ui.control.Label
        TasadeincubacinEditField        matlab.ui.control.NumericEditField
        TasadevacunacinEditFieldLabel   matlab.ui.control.Label
        TasadevacunacinEditField        matlab.ui.control.NumericEditField
        ModeloDropDownLabel             matlab.ui.control.Label
        ModeloDropDown                  matlab.ui.control.DropDown
        SimularButton                   matlab.ui.control.Button
        TiempoEditFieldLabel            matlab.ui.control.Label
        TiempoEditField                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeloDropDown
        function ModeloDropDownValueChanged(app, event)
            modelo = app.ModeloDropDown.Value;
            cla(app.UIAxes) 

            % Ocultar por defecto
            app.ExpuestosEditField.Visible = 'off';
            app.TasadeincubacinEditField.Visible = 'off';
            app.TasadevacunacinEditField.Visible = 'off';
            app.RecuperadosEditField.Visible = 'off';
            app.TasaderecuperacinEditField.Visible = 'off';
            
            
            app.SusceptiblesEditField.Visible = 'on';
            app.InfectadosEditField.Visible = 'on';
            app.TasadetransmisinEditField.Visible = 'on';
            app.TiempoEditField.Visible = 'on';
            app.SusceptiblesEditField.Value = 9900;
            app.InfectadosEditField.Value = 100;
            app.TasadetransmisinEditField.Value = 0.5;
            app.TiempoEditField.Value = 150;


            % Ahora según el modelo, mostramos lo adicional
            switch modelo
                case {'SIS'}
                    app.TasaderecuperacinEditField.Visible = 'on';
                otherwise
                    app.TasaderecuperacinEditField.Value = 0.1;
            end
            
            switch modelo
                case {'SIR'}
                    app.RecuperadosEditField.Visible = 'on';
                    app.TasaderecuperacinEditField.Visible = 'on';
                otherwise
                    app.RecuperadosEditField.Value = 0;
                    app.TasaderecuperacinEditField.Value = 0.1;
            end
            
            switch modelo
                case {'SEIR', 'SEIRV'}
                    app.ExpuestosEditField.Visible = 'on';
                    app.TasadeincubacinEditField.Visible = 'on';
                    app.RecuperadosEditField.Visible = 'on';
                    app.TasaderecuperacinEditField.Visible = 'on';
                otherwise
                    app.ExpuestosEditField.Value = 0;
                    app.TasadeincubacinEditField.Value = 0.2;
                    app.RecuperadosEditField.Value = 0;
                    app.TasaderecuperacinEditField.Value = 0.1;
            end
            
        
            switch modelo
                case {'SIRV', 'SEIRV'}
                    app.TasadevacunacinEditField.Visible = 'on';
                    app.RecuperadosEditField.Visible = 'on';
                    app.TasaderecuperacinEditField.Visible = 'on';
                  
                otherwise
                    app.TasadevacunacinEditField.Value = 0.008;
                    app.RecuperadosEditField.Value = 0;
                    app.TasaderecuperacinEditField.Value = 0.1;
            end

        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            modelo = app.ModeloDropDown.Value;
        
            % Leer parámetros
            S = app.SusceptiblesEditField.Value;
            E = app.ExpuestosEditField.Value;
            I = app.InfectadosEditField.Value;
            R = app.RecuperadosEditField.Value;
            beta = app.TasadetransmisinEditField.Value;
            gamma = app.TasaderecuperacinEditField.Value;
            sigma = app.TasadeincubacinEditField.Value;
            nu = app.TasadevacunacinEditField.Value;
            
            N = S + E + I + R;
            
            tmax = app.TiempoEditField.Value;
            tspan = [0 tmax];
        
            switch modelo
                case 'SI'
                    y0 = [S, I];
                    f = @(t, y)[-beta*y(1)*y(2) / N;
                                 beta*y(1)*y(2) / N];
        
                case 'SIS'
                    y0 = [S, I];
                    f = @(t, y)[-beta*y(1)*y(2) / N + gamma*y(2);
                                 beta*y(1)*y(2) / N - gamma*y(2)];
        
                case 'SIR'
                    y0 = [S, I, R];
                    f = @(t, y)[-beta*y(1)*y(2) / N;
                                 beta*y(1)*y(2) / N - gamma*y(2);
                                 gamma*y(2)];
        
                case 'SEIR'
                    y0 = [S, E, I, R];
                    f = @(t, y)[-beta*y(1)*y(3) / N;
                                 beta*y(1)*y(3) / N - sigma*y(2);
                                 sigma*y(2) - gamma*y(3);
                                 gamma*y(3)];
        
                case 'SIRV'
                    y0 = [S, I, R]; 
                    f = @(t, y)[-beta*y(1)*y(2) / N - nu*y(1);
                                 beta*y(1)*y(2) / N - gamma*y(2);
                                 gamma*y(2) + nu*y(1)];
                                 
        
                case 'SEIRV'
                    y0 = [S, E, I, R]; 
                    f = @(t, y)[-beta*y(1)*y(3) / N - nu*y(1);
                                 beta*y(1)*y(3) / N - sigma*y(2);
                                 sigma*y(2) - gamma*y(3);
                                 gamma*y(3) + nu*y(1)];
                                
            end
        
            [t, y] = ode45(f, tspan, y0);
        
            % Graficar
            plot(app.UIAxes, t, y, 'LineWidth', 2);
            title(app.UIAxes, ['Modelo ', modelo]);
            xlabel(app.UIAxes, 'Tiempo');
            ylabel(app.UIAxes, 'Personas');
            
            % Generar leyenda dinámica
            legend(app.UIAxes, generar_leyenda(modelo), 'Location', 'best');

            function labels = generar_leyenda(modelo)
                switch modelo
                    case 'SI'
                        labels = {'S', 'I'};
                    case 'SIS'
                        labels = {'S', 'I'};
                    case 'SIR'
                        labels = {'S', 'I', 'R'};
                    case 'SEIR'
                        labels = {'S', 'E', 'I', 'R'};
                    case 'SIRV'
                        labels = {'S', 'I', 'R', 'V'};
                    case 'SEIRV'
                        labels = {'S', 'E', 'I', 'R', 'V'};
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SusceptiblesEditFieldLabel = uilabel(app.UIFigure);
            app.SusceptiblesEditFieldLabel.Text = 'Susceptibles';
            app.SusceptiblesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfectadosEditFieldLabel = uilabel(app.UIFigure);
            app.InfectadosEditFieldLabel.Text = 'Infectados';
            app.InfectadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecuperadosEditFieldLabel = uilabel(app.UIFigure);
            app.RecuperadosEditFieldLabel.Text = 'Recuperados';
            app.RecuperadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExpuestosEditFieldLabel = uilabel(app.UIFigure);
            app.ExpuestosEditFieldLabel.Text = 'Expuestos';
            app.ExpuestosEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasadetransmisinEditFieldLabel = uilabel(app.UIFigure);
            app.TasadetransmisinEditFieldLabel.Text = 'Tasa de transmisión';
            app.TasadetransmisinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasaderecuperacinEditFieldLabel = uilabel(app.UIFigure);
            app.TasaderecuperacinEditFieldLabel.Text = 'Tasa de recuperación';
            app.TasaderecuperacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasadeincubacinEditFieldLabel = uilabel(app.UIFigure);
            app.TasadeincubacinEditFieldLabel.Text = 'Tasa de incubación';
            app.TasadeincubacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.TasadevacunacinEditFieldLabel = uilabel(app.UIFigure);
            app.TasadevacunacinEditFieldLabel.Text = 'Tasa de vacunación';
            app.TasadevacunacinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModeloDropDownLabel = uilabel(app.UIFigure);
            app.ModeloDropDownLabel.Text = 'Modelo';
            app.ModeloDropDown = uidropdown(app.UIFigure);
            app.ModeloDropDown.Items = {'SI', 'SIS', 'SIR', 'SEIR', 'SIRV', 'SEIRV'};
            app.ModeloDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeloDropDownValueChanged, true);
            app.ModeloDropDown.Value = 'SI';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplicacion_modelos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end