classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BetaEditFieldLabel          matlab.ui.control.Label
        BetaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        SusceptiblesEditFieldLabel  matlab.ui.control.Label
        SusceptiblesEditField       matlab.ui.control.NumericEditField
        InfectadosEditFieldLabel    matlab.ui.control.Label
        InfectadosEditField         matlab.ui.control.NumericEditField
        RecuperadosEditFieldLabel   matlab.ui.control.Label
        RecuperadosEditField        matlab.ui.control.NumericEditField
        DropDownLabel               matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        SimularButton               matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ExpuestosEditFieldLabel     matlab.ui.control.Label
        ExpuestosEditField          matlab.ui.control.NumericEditField
        SigmaEditFieldLabel         matlab.ui.control.Label
        SigmaEditField              matlab.ui.control.NumericEditField
        NuEditFieldLabel            matlab.ui.control.Label
        NuEditField                 matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
        function SimularButtonPushed(app, event)
            % Leer valores visibles solo
            if strcmp(app.SusceptiblesEditField.Visible, 'on')
                S0 = app.SusceptiblesEditField.Value;
                assignin('base', 'S0', S0);
            end
            if strcmp(app.InfectadosEditField.Visible, 'on')
                I0 = app.InfectadosEditField.Value;
                assignin('base', 'I0', I0);
            end
            if strcmp(app.RecuperadosEditField.Visible, 'on')
                R0 = app.RecuperadosEditField.Value;
                assignin('base', 'R0', R0);
            end
            if strcmp(app.ExpuestosEditField.Visible, 'on')
                E0 = app.ExpuestosEditField.Value;
                assignin('base', 'E0', E0);
            end
            if strcmp(app.BetaEditField.Visible, 'on')
                beta = app.BetaEditField.Value;
                assignin('base', 'beta', beta);
            end
            if strcmp(app.GammaEditField.Visible, 'on')
                gamma = app.GammaEditField.Value;
                assignin('base', 'gamma', gamma);
            end
            if strcmp(app.SigmaEditField.Visible, 'on')
                sigma = app.SigmaEditField.Value;
                assignin('base', 'sigma', sigma);
            end
            if strcmp(app.NuEditField.Visible, 'on')
                nu = app.NuEditField.Value;
                assignin('base', 'nu', nu);
            end

            % Ejecutar modelo seleccionado
            modeloSeleccionado = app.DropDown.Value;
            try
                simOut = sim(['modelo_' modeloSeleccionado], 'ReturnWorkspaceOutputs', 'on');
            catch ME
                uialert(app.UIFigure, ['Error en simulación: ', ME.message], 'Error');
                return;
            end

            % Extraer resultados
            tiempo = simOut.tout;
            yout = simOut.yout;

            % Extraer variables según modelo (solo las visibles)

            S = [];
            I = [];
            R = [];
            E = [];
            
            if strcmp(app.SusceptiblesEditField.Visible, 'on')
                try
                    S = yout.getElement('S').Values.Data;
                catch
                    S = [];
                end
            end
            
            if strcmp(app.InfectadosEditField.Visible, 'on')
                try
                    I = yout.getElement('I').Values.Data;
                catch
                    I = [];
                end
            end
            
            if strcmp(app.RecuperadosEditField.Visible, 'on')
                try
                    R = yout.getElement('R').Values.Data;
                catch
                    R = [];
                end
            end

if strcmp(app.ExpuestosEditField.Visible, 'on')
    try
        E = yout.getElement('E').Values.Data;
    catch
        E = [];
    end
end

            % Graficar
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'tight');

            if ~isempty(S), plot(app.UIAxes, tiempo, S, 'b', 'DisplayName', 'Susceptibles'); end
            if ~isempty(I), plot(app.UIAxes, tiempo, I, 'r', 'DisplayName', 'Infectados'); end
            if ~isempty(R), plot(app.UIAxes, tiempo, R, 'g', 'DisplayName', 'Recuperados'); end
            if ~isempty(E), plot(app.UIAxes, tiempo, E, 'm', 'DisplayName', 'Expuestos'); end

            hold(app.UIAxes, 'off');
            legend(app.UIAxes, 'Location', 'best');
            title(app.UIAxes, ['Resultado modelo ', modeloSeleccionado]);
            xlabel(app.UIAxes, 'Tiempo');
            ylabel(app.UIAxes, 'Población');
        end

    end      
        end

        % Callback function
        function DropDownValueChanged(app, event)
        modelo = app.DropDown.Value;

            switch modelo
                case 'SI'
                    % Solo S, I, beta, gamma
                    setVisibility(app, true, true, false, false, true, true, false, false);
                case 'SIS'
                    % Solo S, I, beta, gamma
                    setVisibility(app, true, true, false, false, true, true, false, false);
                case 'SIR'
                    % S, I, R, beta, gamma
                    setVisibility(app, true, true, true, false, true, true, false, false);
                case 'SEIR'
                    % S, E, I, R, beta, gamma, sigma
                    setVisibility(app, true, true, true, true, true, true, true, false);
                case 'SIRV'
                    % S, I, R, beta, gamma, nu
                    setVisibility(app, true, true, true, false, true, true, false, true);
                case 'SEIRV'
                    % S, E, I, R, beta, gamma, sigma, nu
                    setVisibility(app, true, true, true, true, true, true, true, true);
                otherwise
                    % Mostrar todos por defecto
                    setVisibility(app, true, true, true, true, true, true, true, true);
            end
            
        function setVisibility(app, S_vis, I_vis, R_vis, E_vis, beta_vis, gamma_vis, sigma_vis, nu_vis)
            % S
            app.SusceptiblesEditField.Visible = bool2onoff(S_vis);
            app.SusceptiblesEditFieldLabel.Visible = bool2onoff(S_vis);

            % I
            app.InfectadosEditField.Visible = bool2onoff(I_vis);
            app.InfectadosEditFieldLabel.Visible = bool2onoff(I_vis);

            % R
            app.RecuperadosEditField.Visible = bool2onoff(R_vis);
            app.RecuperadosEditFieldLabel.Visible = bool2onoff(R_vis);

            % E
            app.ExpuestosEditField.Visible = bool2onoff(E_vis);
            app.ExpuestosEditFieldLabel.Visible = bool2onoff(E_vis);

            % beta
            app.BetaEditField.Visible = bool2onoff(beta_vis);
            app.BetaEditFieldLabel.Visible = bool2onoff(beta_vis);

            % gamma
            app.GammaEditField.Visible = bool2onoff(gamma_vis);
            app.GammaEditFieldLabel.Visible = bool2onoff(gamma_vis);

            % sigma
            app.SigmaEditField.Visible = bool2onoff(sigma_vis);
            app.SigmaEditFieldLabel.Visible = bool2onoff(sigma_vis);

            % nu
            app.NuEditField.Visible = bool2onoff(nu_vis);
            app.NuEditFieldLabel.Visible = bool2onoff(nu_vis);
        end

        % Convierte booleano a 'on'/'off' para propiedad Visible
        function val = bool2onoff(~, b)
            if b
                val = 'on';
            else
                val = 'off';
            end
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SusceptiblesEditFieldLabel = uilabel(app.UIFigure);
            app.SusceptiblesEditFieldLabel.Text = 'Susceptibles';
            app.SusceptiblesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfectadosEditFieldLabel = uilabel(app.UIFigure);
            app.InfectadosEditFieldLabel.Text = 'Infectados';
            app.InfectadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecuperadosEditFieldLabel = uilabel(app.UIFigure);
            app.RecuperadosEditFieldLabel.Text = 'Recuperados';
            app.RecuperadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'SI', 'SIS', 'SIR', 'SEIR', 'SIRV', 'SEIRV'};
            app.DropDown.Value = 'SI';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ExpuestosEditFieldLabel = uilabel(app.UIFigure);
            app.ExpuestosEditFieldLabel.Text = 'Expuestos';
            app.ExpuestosEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NuEditFieldLabel = uilabel(app.UIFigure);
            app.NuEditFieldLabel.Text = 'Nu';
            app.NuEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end