classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        SusceptiblesEditFieldLabel  matlab.ui.control.Label
        SusceptiblesEditField       matlab.ui.control.NumericEditField
        InfectadosEditFieldLabel    matlab.ui.control.Label
        InfectadosEditField         matlab.ui.control.NumericEditField
        RecuperadosEditFieldLabel   matlab.ui.control.Label
        RecuperadosEditField        matlab.ui.control.NumericEditField
        ExpuestosEditFieldLabel     matlab.ui.control.Label
        ExpuestosEditField          matlab.ui.control.NumericEditField
        BetaEditFieldLabel          matlab.ui.control.Label
        BetaEditField               matlab.ui.control.NumericEditField
        GammaEditFieldLabel         matlab.ui.control.Label
        GammaEditField              matlab.ui.control.NumericEditField
        SigmaEditFieldLabel         matlab.ui.control.Label
        SigmaEditField              matlab.ui.control.NumericEditField
        NuEditFieldLabel            matlab.ui.control.Label
        NuEditField                 matlab.ui.control.NumericEditField
        DropDownLabel               matlab.ui.control.Label
        DropDown                    matlab.ui.control.DropDown
        SimularButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            modelo = app.DropDown.Value;
        
            % Desactivar todos los campos
            setCampos(app, 'off');
        
            % Activar según modelo
            switch modelo
                case 'SI'
                    app.SusceptiblesField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    
                case 'SIS'
                    app.SusceptiblesField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    app.GammaField.Enable = 'on';
        
                    app.RecuperadosField.Enable = 'off';
                    app.ExpuestosField.Enable = 'off';
                    app.SigmaField.Enable = 'off';
                    app.NuField.Enable = 'off';
                case 'SIR'
                    app.SusceptiblesField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.RecuperadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    app.GammaField.Enable = 'on';
                case 'SEIR'
                    app.SusceptiblesField.Enable = 'on';
                    app.ExpuestosField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.RecuperadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    app.GammaField.Enable = 'on';
                    app.SigmaField.Enable = 'on';
                case 'SIRV'
                    app.SusceptiblesField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.RecuperadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    app.GammaField.Enable = 'on';
                    app.NuField.Enable = 'on';
        
                case 'SEIRV'
                    app.SusceptiblesField.Enable = 'on';
                    app.ExpuestosField.Enable = 'on';
                    app.InfectadosField.Enable = 'on';
                    app.RecuperadosField.Enable = 'on';
                    app.BetaField.Enable = 'on';
                    app.GammaField.Enable = 'on';
                    app.SigmaField.Enable = 'on';
                    app.NuField.Enable = 'on';
                          
            end
        end

        function setCampos(app, estado)
            campos = {
                app.SusceptiblesEditField, ...
                app.InfectadosEditField, ...
                app.RecuperadosEditField, ...
                app.ExpuestosEditField, ...
                app.BetaEditField, ...
                app.GammaEditField, ...
                app.SigmaEditField, ...
                app.NuEditField
            };
        
            for i = 1:length(campos)
                campos{i}.Enable = estado;
            end
        end

        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            modelo = app.DropDown.Value;
            tspan = [0 100];

            switch modelo
                case 'SI'
                    Susceptibles = app.SusceptiblesField.Value;
                    Infectados = app.InfectadosField.Value;
                    beta = app.BetaField.Value;
                    y0 = [Susceptibles Infectados];
                    [t, y] = ode45(@(t,y) siODE(t,y,beta), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'I'});
                    
                
                case 'SIS'
                    Susceptibles = app.SusceptiblesField.Value;
                    Infectados = app.InfectadosField.Value;
                    beta = app.BetaField.Value;
                    gamma = app.GammaField.Value;
                    y0 = [Susceptibles Infectados];
                    [t, y] = ode45(@(t, y) sisODE(t, y, beta, gamma), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'I'});

                case 'SIR'
                    Susceptibles = app.SusceptiblesField.Value;
                    Infectados = app.InfectadosField.Value;
                    Recuperados = app.RecuperadosField.Value;
                    beta = app.BetaField.Value;
                    gamma = app.GammaField.Value;
                    y0 = [Susceptibles Infectados Recuperados];
                    [t, y] = ode45(@(t,y) sirODE(t,y,beta,gamma), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'I', 'R'});
        
                case 'SEIR'
                    Susceptibles = app.SusceptiblesField.Value;
                    Expuestos = app.ExpuestosField.Value;
                    Infectados = app.InfectadosField.Value;
                    Recuperados = app.RecuperadosField.Value;
                    beta = app.BetaField.Value;
                    sigma = app.SigmaField.Value;
                    gamma = app.GammaField.Value;
                    y0 = [Susceptibles Expuestos Infectados Recuperados];
                    [t, y] = ode45(@(t,y) seirODE(t,y,beta,sigma,gamma), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'E', 'I', 'R'});
                    
                case 'SIRV'
                    Susceptibles = app.SusceptiblesField.Value;
                    Infectados = app.InfectadosField.Value;
                    Recuperados = app.RecuperadosField.Value;
                    beta = app.BetaField.Value;
                    gamma = app.GammaField.Value;
                    alpha = app.AlphaField.Value;
                    y0 = [Susceptibles Infectados Recuperados];
                    [t,y] = ode45(@(t,y) sirvODE(t,y,beta,gamma,alpha), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'I', 'R'});

                case 'SEIRV'
                    Susceptibles = app.SusceptiblesField.Value;
                    Expuestos = app.ExpuestosField.Value;
                    Infectados = app.InfectadosField.Value;
                    Recuperados = app.RecuperadosField.Value;
                    beta = app.BetaField.Value;
                    gamma = app.GammaField.Value;
                    sigma = app.SigmaField.Value;
                    alpha = app.AlphaField.Value;
                    y0 = [Susceptibles Expuestos Infectados Recuperados];
                    [t,y] = ode45(@(t,y) seirvODE(t,y,beta,sigma,gamma,alpha), tspan, y0);
                    plot(app.UIAxes, t, y);
                    legend(app.UIAxes, {'S', 'E', 'I', 'R', 'V'});
            end
            
        function dydt = siODE(~, y, beta)
            S = y(1);
            I = y(2);
            N = S + I;
            dS = -beta*S*I/N;
            dI = beta*S*I/N;
            dydt = [dS; dI];
        end
        
        function dydt = sisODE(~, y, beta, gamma)
            S = y(1);
            I = y(2);
            N = S + I;
            dS = -beta*S*I/N + gamma*I;
            dI = beta*S*I/N - gamma*I;
            dydt = [dS; dI];
        end
 
        function dydt = sirODE(~, y, beta, gamma)
            S = y(1);
            I = y(2);
            R = y(3);
            N = S + I + R;
            dS = -beta*S*I/N;
            dI = beta*S*I/N - gamma*I;
            dR = gamma*I;
            dydt = [dS; dI; dR];
        end
        
        function dydt = seirODE(~, y, beta, sigma, gamma)
            S = y(1);
            E = y(2);
            I = y(3);
            R = y(4);
            N = S + E + I + R;
            dS = -beta*S*I/N;
            dE = beta*S*I/N - sigma*E;
            dI = sigma*E - gamma*I;
            dR = gamma*I;
            dydt = [dS; dE; dI; dR];
        end     
        
        function dydt = sirvODE(~, y, beta, gamma, nu)
            S = y(1); I = y(2); R = y(3); 
            N = S + I + R + V;
            dS = -beta*S*I/N - nu*S;
            dI = beta*S*I/N - gamma*I;
            dR = gamma*I + nu*S;
            dydt = [dS; dI; dR];
        end
        
        function dydt = seirvODE(~, y, beta, sigma, gamma, nu)
            S = y(1); E = y(2); I = y(3); R = y(4);
            N = S + E + I + R;
            dS = -beta*S*I/N - nu*S;
            dE = beta*S*I/N - sigma*E;
            dI = sigma*E - gamma*I;
            dR = gamma*I + nu*S;
            dydt = [dS; dE; dI; dR];
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SusceptiblesEditFieldLabel = uilabel(app.UIFigure);
            app.SusceptiblesEditFieldLabel.Text = 'Susceptibles';
            app.SusceptiblesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InfectadosEditFieldLabel = uilabel(app.UIFigure);
            app.InfectadosEditFieldLabel.Text = 'Infectados';
            app.InfectadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecuperadosEditFieldLabel = uilabel(app.UIFigure);
            app.RecuperadosEditFieldLabel.Text = 'Recuperados';
            app.RecuperadosEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExpuestosEditFieldLabel = uilabel(app.UIFigure);
            app.ExpuestosEditFieldLabel.Text = 'Expuestos';
            app.ExpuestosEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.GammaEditFieldLabel = uilabel(app.UIFigure);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NuEditFieldLabel = uilabel(app.UIFigure);
            app.NuEditFieldLabel.Text = 'Nu';
            app.NuEditField = uieditfield(app.UIFigure, 'numeric');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'SI', 'SIS', 'SIR', 'SEIR', 'SIRV', 'SEIRV'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'SI';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end